classdef appInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnviarParmetrosButton         matlab.ui.control.Button
        nguloentreo1e2eloSliderLabel  matlab.ui.control.Label
        nguloentreo1e2eloSlider       matlab.ui.control.Slider
        ComprimentodasJuntasLabel     matlab.ui.control.Label
        nguloentreoseixoszLabel       matlab.ui.control.Label
        nguloentreo2e3eloSliderLabel  matlab.ui.control.Label
        nguloentreo2e3eloSlider       matlab.ui.control.Slider
        nguloentreo3e4eloSliderLabel  matlab.ui.control.Label
        nguloentreo3e4eloSlider       matlab.ui.control.Slider
        ngulodasjuntasRotativasLabel  matlab.ui.control.Label
        ngulodajunta1SliderLabel      matlab.ui.control.Label
        ngulodajunta1Slider           matlab.ui.control.Slider
        ngulodajunta2SliderLabel      matlab.ui.control.Label
        ngulodajunta2Slider           matlab.ui.control.Slider
        ngulodajunta3SliderLabel      matlab.ui.control.Label
        ngulodajunta3Slider           matlab.ui.control.Slider
        nguloentreoseixosxLabel       matlab.ui.control.Label
        ngulo1SliderLabel             matlab.ui.control.Label
        ngulo1Slider                  matlab.ui.control.Slider
        ngulo2SliderLabel             matlab.ui.control.Label
        ngulo2Slider                  matlab.ui.control.Slider
        ngulo3SliderLabel             matlab.ui.control.Label
        ngulo3Slider                  matlab.ui.control.Slider
        ImplementaoCinemticaDiretaLabel  matlab.ui.control.Label
        Comprimentodoelo1EditFieldLabel  matlab.ui.control.Label
        Comprimentodoelo1EditField    matlab.ui.control.EditField
        Comprimentodoelo2EditFieldLabel  matlab.ui.control.Label
        Comprimentodoelo2EditField    matlab.ui.control.EditField
        Comprimentodoelo3EditFieldLabel  matlab.ui.control.Label
        Comprimentodoelo3EditField    matlab.ui.control.EditField
        nguloentreo1e2eloGaugeLabel   matlab.ui.control.Label
        nguloentreo1e2eloGauge        matlab.ui.control.SemicircularGauge
        nguloentreo2e3eloGaugeLabel   matlab.ui.control.Label
        nguloentreo2e3eloGauge        matlab.ui.control.SemicircularGauge
        nguloentreo3e4eloGaugeLabel   matlab.ui.control.Label
        nguloentreo3e4eloGauge        matlab.ui.control.SemicircularGauge
        ngulo1GaugeLabel              matlab.ui.control.Label
        ngulo1Gauge                   matlab.ui.control.SemicircularGauge
        ngulo2GaugeLabel              matlab.ui.control.Label
        ngulo2Gauge                   matlab.ui.control.SemicircularGauge
        ngulo3GaugeLabel              matlab.ui.control.Label
        ngulo3Gauge                   matlab.ui.control.SemicircularGauge
        ngulodaJunta1GaugeLabel       matlab.ui.control.Label
        ngulodaJunta1Gauge            matlab.ui.control.SemicircularGauge
        ngulodaJunta2GaugeLabel       matlab.ui.control.Label
        ngulodaJunta2Gauge            matlab.ui.control.SemicircularGauge
        ngulodaJunta3GaugeLabel       matlab.ui.control.Label
        ngulodaJunta3Gauge            matlab.ui.control.SemicircularGauge
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnviarParmetrosButton
        function EnviarParmetrosButtonPushed(app, event)
            % Comprimento das juntas
            j1_value = str2double(app.Comprimentodoelo1EditField.Value);
            j2_value = str2double(app.Comprimentodoelo2EditField.Value);
            j3_value = str2double(app.Comprimentodoelo3EditField.Value);
            
            % Ângulos entre os eixos z
            a1_value = app.nguloentreo1e2eloSlider.Value;
            a2_value = app.nguloentreo2e3eloSlider.Value;
            a3_value = app.nguloentreo3e4eloSlider.Value;
            
            % Ângulos das juntas rotativas
            q1_value = app.ngulodajunta1Slider.Value;
            q2_value = app.ngulodajunta2Slider.Value;
            q3_value = app.ngulodajunta3Slider.Value;
            
            % Ângulos entre os eixos x
            t1_value = app.ngulo1Slider.Value;
            t2_value = app.ngulo2Slider.Value;
            t3_value = app.ngulo3Slider.Value;
            
            % Envia os parâmetros para a função principal
            Tipos_Comuns_RRR(j1_value, j2_value, j3_value, a1_value, a2_value, a3_value, q1_value, q2_value, q3_value, t1_value, t2_value, t3_value)
        end

        % Value changing function: nguloentreo1e2eloSlider
        function nguloentreo1e2eloSliderValueChanging(app, event)
            changingValue = event.Value;
            app.nguloentreo1e2eloGauge.Value = changingValue;
        end

        % Value changing function: nguloentreo2e3eloSlider
        function nguloentreo2e3eloSliderValueChanging(app, event)
            changingValue = event.Value;
            app.nguloentreo2e3eloGauge.Value = changingValue;
        end

        % Value changing function: nguloentreo3e4eloSlider
        function nguloentreo3e4eloSliderValueChanging(app, event)
            changingValue = event.Value;
            app.nguloentreo3e4eloGauge.Value = changingValue;
        end

        % Value changing function: ngulo1Slider
        function ngulo1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulo1Gauge.Value = changingValue;
        end

        % Value changing function: ngulo2Slider
        function ngulo2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulo2Gauge.Value = changingValue;
        end

        % Value changing function: ngulo3Slider
        function ngulo3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulo3Gauge.Value = changingValue;
        end

        % Value changing function: ngulodajunta1Slider
        function ngulodajunta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulodaJunta1Gauge.Value = changingValue;
        end

        % Value changing function: ngulodajunta2Slider
        function ngulodajunta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulodaJunta2Gauge.Value = changingValue;
        end

        % Value changing function: ngulodajunta3Slider
        function ngulodajunta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.ngulodaJunta3Gauge.Value = changingValue;
        end

        % Value changing function: Comprimentodoelo1EditField
        function Comprimentodoelo1EditFieldValueChanging(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnviarParmetrosButton = uibutton(app.UIFigure, 'push');
            app.EnviarParmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarParmetrosButtonPushed, true);
            app.EnviarParmetrosButton.Text = 'Enviar Parâmetros';
            app.nguloentreo1e2eloSliderLabel = uilabel(app.UIFigure);
            app.nguloentreo1e2eloSliderLabel.Text = 'Ângulo entre o 1° e 2° elo: ';
            app.nguloentreo1e2eloSlider = uislider(app.UIFigure);
            app.nguloentreo1e2eloSlider.Limits = [0 180];
            app.nguloentreo1e2eloSlider.ValueChangingFcn = createCallbackFcn(app, @nguloentreo1e2eloSliderValueChanging, true);
            app.ComprimentodasJuntasLabel = uilabel(app.UIFigure);
            app.ComprimentodasJuntasLabel.Text = 'Comprimento das Juntas';
            app.nguloentreoseixoszLabel = uilabel(app.UIFigure);
            app.nguloentreoseixoszLabel.Text = 'Ângulo entre os eixos z';
            app.nguloentreo2e3eloSliderLabel = uilabel(app.UIFigure);
            app.nguloentreo2e3eloSliderLabel.Text = 'Ângulo entre o 2° e 3° elo: ';
            app.nguloentreo2e3eloSlider = uislider(app.UIFigure);
            app.nguloentreo2e3eloSlider.Limits = [0 180];
            app.nguloentreo2e3eloSlider.ValueChangingFcn = createCallbackFcn(app, @nguloentreo2e3eloSliderValueChanging, true);
            app.nguloentreo3e4eloSliderLabel = uilabel(app.UIFigure);
            app.nguloentreo3e4eloSliderLabel.Text = 'Ângulo entre o 3° e 4° elo: ';
            app.nguloentreo3e4eloSlider = uislider(app.UIFigure);
            app.nguloentreo3e4eloSlider.Limits = [0 180];
            app.nguloentreo3e4eloSlider.ValueChangingFcn = createCallbackFcn(app, @nguloentreo3e4eloSliderValueChanging, true);
            app.ngulodasjuntasRotativasLabel = uilabel(app.UIFigure);
            app.ngulodasjuntasRotativasLabel.Text = 'Ângulo das juntas Rotativas';
            app.ngulodajunta1SliderLabel = uilabel(app.UIFigure);
            app.ngulodajunta1SliderLabel.Text = 'Ângulo da junta 1: ';
            app.ngulodajunta1Slider = uislider(app.UIFigure);
            app.ngulodajunta1Slider.Limits = [0 180];
            app.ngulodajunta1Slider.ValueChangingFcn = createCallbackFcn(app, @ngulodajunta1SliderValueChanging, true);
            app.ngulodajunta2SliderLabel = uilabel(app.UIFigure);
            app.ngulodajunta2SliderLabel.Text = 'Ângulo da junta 2: ';
            app.ngulodajunta2Slider = uislider(app.UIFigure);
            app.ngulodajunta2Slider.Limits = [0 180];
            app.ngulodajunta2Slider.ValueChangingFcn = createCallbackFcn(app, @ngulodajunta2SliderValueChanging, true);
            app.ngulodajunta3SliderLabel = uilabel(app.UIFigure);
            app.ngulodajunta3SliderLabel.Text = 'Ângulo da junta 3: ';
            app.ngulodajunta3Slider = uislider(app.UIFigure);
            app.ngulodajunta3Slider.Limits = [0 180];
            app.ngulodajunta3Slider.ValueChangingFcn = createCallbackFcn(app, @ngulodajunta3SliderValueChanging, true);
            app.nguloentreoseixosxLabel = uilabel(app.UIFigure);
            app.nguloentreoseixosxLabel.Text = 'Ângulo entre os eixos x';
            app.ngulo1SliderLabel = uilabel(app.UIFigure);
            app.ngulo1SliderLabel.Text = 'Ângulo 1: ';
            app.ngulo1Slider = uislider(app.UIFigure);
            app.ngulo1Slider.Limits = [0 180];
            app.ngulo1Slider.ValueChangingFcn = createCallbackFcn(app, @ngulo1SliderValueChanging, true);
            app.ngulo2SliderLabel = uilabel(app.UIFigure);
            app.ngulo2SliderLabel.Text = 'Ângulo 2: ';
            app.ngulo2Slider = uislider(app.UIFigure);
            app.ngulo2Slider.Limits = [0 180];
            app.ngulo2Slider.ValueChangingFcn = createCallbackFcn(app, @ngulo2SliderValueChanging, true);
            app.ngulo3SliderLabel = uilabel(app.UIFigure);
            app.ngulo3SliderLabel.Text = 'Ângulo 3: ';
            app.ngulo3Slider = uislider(app.UIFigure);
            app.ngulo3Slider.Limits = [0 180];
            app.ngulo3Slider.ValueChangingFcn = createCallbackFcn(app, @ngulo3SliderValueChanging, true);
            app.ImplementaoCinemticaDiretaLabel = uilabel(app.UIFigure);
            app.ImplementaoCinemticaDiretaLabel.Text = 'Implementação Cinemática Direta';
            app.Comprimentodoelo1EditFieldLabel = uilabel(app.UIFigure);
            app.Comprimentodoelo1EditFieldLabel.Text = 'Comprimento do elo 1: ';
            app.Comprimentodoelo1EditField = uieditfield(app.UIFigure, 'text');
            app.Comprimentodoelo1EditField.ValueChangingFcn = createCallbackFcn(app, @Comprimentodoelo1EditFieldValueChanging, true);
            app.Comprimentodoelo2EditFieldLabel = uilabel(app.UIFigure);
            app.Comprimentodoelo2EditFieldLabel.Text = 'Comprimento do elo 2: ';
            app.Comprimentodoelo2EditField = uieditfield(app.UIFigure, 'text');
            app.Comprimentodoelo3EditFieldLabel = uilabel(app.UIFigure);
            app.Comprimentodoelo3EditFieldLabel.Text = 'Comprimento do elo 3: ';
            app.Comprimentodoelo3EditField = uieditfield(app.UIFigure, 'text');
            app.nguloentreo1e2eloGaugeLabel = uilabel(app.UIFigure);
            app.nguloentreo1e2eloGaugeLabel.Text = 'Ângulo entre o 1° e 2° elo: ';
            app.nguloentreo1e2eloGauge = uigauge(app.UIFigure, 'semicircular');
            app.nguloentreo1e2eloGauge.Limits = [0 180];
            app.nguloentreo2e3eloGaugeLabel = uilabel(app.UIFigure);
            app.nguloentreo2e3eloGaugeLabel.Text = 'Ângulo entre o 2° e 3° elo: ';
            app.nguloentreo2e3eloGauge = uigauge(app.UIFigure, 'semicircular');
            app.nguloentreo2e3eloGauge.Limits = [0 180];
            app.nguloentreo3e4eloGaugeLabel = uilabel(app.UIFigure);
            app.nguloentreo3e4eloGaugeLabel.Text = 'Ângulo entre o 3° e 4° elo: ';
            app.nguloentreo3e4eloGauge = uigauge(app.UIFigure, 'semicircular');
            app.nguloentreo3e4eloGauge.Limits = [0 180];
            app.ngulo1GaugeLabel = uilabel(app.UIFigure);
            app.ngulo1GaugeLabel.Text = 'Ângulo 1';
            app.ngulo1Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulo1Gauge.Limits = [0 180];
            app.ngulo2GaugeLabel = uilabel(app.UIFigure);
            app.ngulo2GaugeLabel.Text = 'Ângulo 2';
            app.ngulo2Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulo2Gauge.Limits = [0 180];
            app.ngulo3GaugeLabel = uilabel(app.UIFigure);
            app.ngulo3GaugeLabel.Text = 'Ângulo 3';
            app.ngulo3Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulo3Gauge.Limits = [0 180];
            app.ngulodaJunta1GaugeLabel = uilabel(app.UIFigure);
            app.ngulodaJunta1GaugeLabel.Text = 'Ângulo da Junta 1';
            app.ngulodaJunta1Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulodaJunta1Gauge.Limits = [0 180];
            app.ngulodaJunta2GaugeLabel = uilabel(app.UIFigure);
            app.ngulodaJunta2GaugeLabel.Text = 'Ângulo da Junta 2';
            app.ngulodaJunta2Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulodaJunta2Gauge.Limits = [0 180];
            app.ngulodaJunta3GaugeLabel = uilabel(app.UIFigure);
            app.ngulodaJunta3GaugeLabel.Text = 'Ângulo da Junta 3';
            app.ngulodaJunta3Gauge = uigauge(app.UIFigure, 'semicircular');
            app.ngulodaJunta3Gauge.Limits = [0 180];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end